classdef JPcalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Ion1DropDownLabel            matlab.ui.control.Label
        IonDropDown1                 matlab.ui.control.DropDown
        Ion2DropDownLabel            matlab.ui.control.Label
        IonDropDown2                 matlab.ui.control.DropDown
        Ion3DropDownLabel            matlab.ui.control.Label
        IonDropDown3                 matlab.ui.control.DropDown
        Ion4DropDownLabel            matlab.ui.control.Label
        IonDropDown4                 matlab.ui.control.DropDown
        Ion5DropDownLabel            matlab.ui.control.Label
        IonDropDown5                 matlab.ui.control.DropDown
        Ion6DropDownLabel            matlab.ui.control.Label
        IonDropDown6                 matlab.ui.control.DropDown
        Ion7DropDownLabel            matlab.ui.control.Label
        IonDropDown7                 matlab.ui.control.DropDown
        Ion8DropDownLabel            matlab.ui.control.Label
        IonDropDown8                 matlab.ui.control.DropDown
        CalculateButton              matlab.ui.control.Button
        ConcentrationpipettemMLabel  matlab.ui.control.Label
        ConcentrationbathmMLabel     matlab.ui.control.Label
        MobilityLabel                matlab.ui.control.Label
        LJPLabel                     matlab.ui.control.Label
        ResultsField                 matlab.ui.control.TextArea
        ValenceLabel                 matlab.ui.control.Label
        TempCLabel                   matlab.ui.control.Label
        TemperatureField             matlab.ui.control.NumericEditField
        solutionC1                   matlab.ui.control.NumericEditField
        pipetteC1                    matlab.ui.control.NumericEditField
        solutionC2                   matlab.ui.control.NumericEditField
        pipetteC2                    matlab.ui.control.NumericEditField
        solutionC3                   matlab.ui.control.NumericEditField
        pipetteC3                    matlab.ui.control.NumericEditField
        solutionC4                   matlab.ui.control.NumericEditField
        pipetteC4                    matlab.ui.control.NumericEditField
        solutionC5                   matlab.ui.control.NumericEditField
        pipetteC5                    matlab.ui.control.NumericEditField
        solutionC6                   matlab.ui.control.NumericEditField
        pipetteC6                    matlab.ui.control.NumericEditField
        solutionC7                   matlab.ui.control.NumericEditField
        pipetteC7                    matlab.ui.control.NumericEditField
        solutionC8                   matlab.ui.control.NumericEditField
        pipetteC8                    matlab.ui.control.NumericEditField
        solutionC9                   matlab.ui.control.NumericEditField
        pipetteC9                    matlab.ui.control.NumericEditField
        solutionC10                  matlab.ui.control.NumericEditField
        pipetteC10                   matlab.ui.control.NumericEditField
        valence1                     matlab.ui.control.NumericEditField
        valence2                     matlab.ui.control.NumericEditField
        valence3                     matlab.ui.control.NumericEditField
        valence4                     matlab.ui.control.NumericEditField
        valence5                     matlab.ui.control.NumericEditField
        valence6                     matlab.ui.control.NumericEditField
        valence7                     matlab.ui.control.NumericEditField
        valence8                     matlab.ui.control.NumericEditField
        valence9                     matlab.ui.control.NumericEditField
        valence10                    matlab.ui.control.NumericEditField
        mobility1                    matlab.ui.control.NumericEditField
        mobility2                    matlab.ui.control.NumericEditField
        mVLabel                      matlab.ui.control.Label
        Ion9Label                    matlab.ui.control.Label
        IonDropDown9                 matlab.ui.control.DropDown
        Ion10Label                   matlab.ui.control.Label
        IonDropDown10                matlab.ui.control.DropDown
        mobility3                    matlab.ui.control.NumericEditField
        mobility4                    matlab.ui.control.NumericEditField
        mobility5                    matlab.ui.control.NumericEditField
        mobility6                    matlab.ui.control.NumericEditField
        mobility7                    matlab.ui.control.NumericEditField
        mobility8                    matlab.ui.control.NumericEditField
        mobility9                    matlab.ui.control.NumericEditField
        mobility10                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Ions % Description
        F = 96.485;
        R = 8.3;
        T0 = 273.15;
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            c = {'---',NaN,NaN;
                'Na',1,0.682;
                'K',1,1;
                'Cl',-1,1.0382;
                'Gluconate',-1,0.33;
                'Cs',1,1.05;
                'Ca',2,0.4028;
                'Mg',2,0.361;
                'Choline',1,0.51;
                'Li',1,0.526;
                'NH4',1,1.0001;
                'Rb',1,1.059;
                'TEA',1,0.444;
                'TMA',1,0.611;
                'Ba',2,0.424;
                'Co',2,0.367;
                'Sr',2,0.404;
                'Zn',2,0.359;
                'Cd',2,0.37;
                'Acetate',-1,0.556;
                'Benzoate',-1,0.441;
                'Br',-1,1.063;
                'ClO4',-1,0.916;
                'F',-1,0.753;
                'H2P04',-1,0.45;
                'HCO3',-1,0.605;
                'I',-1,1.0456;
                'NO3',-1,0.972;
                'Picrate',-1,0.411;
                'Propionate',-1,0.487;
                'SCN',-1,0.901;
                'Sulfonate',-1,0.586;
                'HPO4',-2,0.39;
                'SO4',-2,0.544;
                'NMDG',1,0.33;
                'TRIS',1,0.4;
                'HEPES',-1,0.3;
                'Aspartate',-1,0.3;
                'Isethionate',-1,0.52;
                'Glutamate',-1,0.26;
                'EGTA(2-)',-2,0.24;
                'EGTA(3-)',-3,0.25;
                'MES',-1,0.37;
                'MOPS',-1,0.35;
                'TI',1,1.02;
                'Butyrate',-1,0.44;
                'Citrate(3-)',-3,0.318;
                '2MAEth',1,0.49;
                '*custom Ion*',0,0};
            
            
            app.Ions = cell2table(c(:,2:3),'VariableNames',{'valence','mobility'},'RowNames',c(:,1));
            
            isDropDown = arrayfun(@(x) strcmpi(x.Type,'uidropdown'), app.UIFigure.Children); 
            nIons = nnz(isDropDown);
            
            for iIon = 1:nIons
            app.(['IonDropDown',num2str(iIon)]).Items = app.Ions.Row; app.(['IonDropDown',num2str(iIon)]).Tag = num2str(iIon);
            end
            app.UIFigure.Name = 'Junction Potential Calculator';
        end

        % Button pushed function: CalculateButton
        function LF_calculateJP(app, event)
            % find active Ions
            isActive = false(8,1);
            bathC = NaN(8,1);
            pipetteC = NaN(8,1);
            z = NaN(8,1);
            u = NaN(8,1);
            
            for iIon = 1:8
                isActive(iIon) = ~strcmp(app.(['IonDropDown',num2str(iIon)]).Value,'---');
                
                if isActive(iIon)
                    bathC(iIon) = app.(['solutionC',num2str(iIon)]).Value;
                    pipetteC(iIon) = app.(['pipetteC',num2str(iIon)]).Value;
                    z(iIon) = app.(['valence',num2str(iIon)]).Value;
                    u(iIon) = app.(['mobility',num2str(iIon)]).Value;
                end
            end

            bathC = bathC(isActive);
            pipetteC = pipetteC(isActive);
            z = z(isActive);
            u = u(isActive);
                
            T = app.TemperatureField.Value + app.T0;
            SF = sum(z.*u.*(bathC-pipetteC)) / sum(z.^2.*u.*(bathC - pipetteC));
            V = app.R*T/app.F * SF * log(sum(z.^2.*u.*pipetteC)/sum(z.^2.*u.*bathC));
            app.ResultsField.Value = num2str(round(V,2));
        end

        % Value changed function: IonDropDown1, IonDropDown2, 
        % IonDropDown3, IonDropDown4, IonDropDown5, IonDropDown6, 
        % IonDropDown7, IonDropDown8
        function LF_getMobility(app, event)
            % get Ion#
            ionID = event.Source.Tag;
            
            % make fields active
            if strcmpi(event.Value,'---')
            app.(['pipetteC',ionID]).Visible = 0;
            app.(['solutionC',ionID]).Visible = 0;
            app.(['mobility',ionID]).Visible = 0;
            app.(['valence',ionID]).Visible = 0;    
            else
            app.(['pipetteC',ionID]).Visible = 1;
            app.(['solutionC',ionID]).Visible = 1;
            app.(['mobility',ionID]).Visible = 1;
            app.(['valence',ionID]).Visible = 1;
            app.(['mobility',ionID]).Value = app.Ions.mobility(event.Value);
            app.(['valence',ionID]).Value = app.Ions.valence(event.Value);
            end
            % set values

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Ion1DropDownLabel = uilabel(app.UIFigure);
            app.Ion1DropDownLabel.Text = 'Ion #1';
            app.IonDropDown1 = uidropdown(app.UIFigure);
            app.IonDropDown1.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.IonDropDown1.Tooltip = {'select ion'};
            app.Ion2DropDownLabel = uilabel(app.UIFigure);
            app.Ion2DropDownLabel.Text = 'Ion #2';
            app.IonDropDown2 = uidropdown(app.UIFigure);
            app.IonDropDown2.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion3DropDownLabel = uilabel(app.UIFigure);
            app.Ion3DropDownLabel.Text = 'Ion #3';
            app.IonDropDown3 = uidropdown(app.UIFigure);
            app.IonDropDown3.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion4DropDownLabel = uilabel(app.UIFigure);
            app.Ion4DropDownLabel.Text = 'Ion #4';
            app.IonDropDown4 = uidropdown(app.UIFigure);
            app.IonDropDown4.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion5DropDownLabel = uilabel(app.UIFigure);
            app.Ion5DropDownLabel.Text = 'Ion #5';
            app.IonDropDown5 = uidropdown(app.UIFigure);
            app.IonDropDown5.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion6DropDownLabel = uilabel(app.UIFigure);
            app.Ion6DropDownLabel.Text = 'Ion #6';
            app.IonDropDown6 = uidropdown(app.UIFigure);
            app.IonDropDown6.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion7DropDownLabel = uilabel(app.UIFigure);
            app.Ion7DropDownLabel.Text = 'Ion #7';
            app.IonDropDown7 = uidropdown(app.UIFigure);
            app.IonDropDown7.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.Ion8DropDownLabel = uilabel(app.UIFigure);
            app.Ion8DropDownLabel.Text = 'Ion #8';
            app.IonDropDown8 = uidropdown(app.UIFigure);
            app.IonDropDown8.ValueChangedFcn = createCallbackFcn(app, @LF_getMobility, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @LF_calculateJP, true);
            app.CalculateButton.Text = 'Calculate LJP';
            app.ConcentrationpipettemMLabel = uilabel(app.UIFigure);
            app.ConcentrationpipettemMLabel.Text = {'Concentration'; 'pipette (mM)'};
            app.ConcentrationbathmMLabel = uilabel(app.UIFigure);
            app.ConcentrationbathmMLabel.Text = {'Concentration '; 'bath (mM)'};
            app.MobilityLabel = uilabel(app.UIFigure);
            app.MobilityLabel.Text = 'Mobility';
            app.LJPLabel = uilabel(app.UIFigure);
            app.LJPLabel.Text = 'LJP =';
            app.ResultsField = uitextarea(app.UIFigure);
            app.ResultsField.Editable = 'off';
            app.ResultsField.Tooltip = {'Calculated liquid junction potential of the two solutions. For whole-cell recordings'; ' this value must be subtracted from the pipette voltage.'};
            app.ValenceLabel = uilabel(app.UIFigure);
            app.ValenceLabel.Text = 'Valence';
            app.TempCLabel = uilabel(app.UIFigure);
            app.TempCLabel.Text = 'Temp. (°C)';
            app.TemperatureField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureField.Value = 22;
            app.solutionC1 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC1.Limits = [0 Inf];
            app.solutionC1.Visible = 'off';
            app.pipetteC1 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC1.Limits = [0 Inf];
            app.pipetteC1.Visible = 'off';
            app.solutionC2 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC2.Limits = [0 Inf];
            app.solutionC2.Visible = 'off';
            app.pipetteC2 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC2.Limits = [0 Inf];
            app.pipetteC2.Visible = 'off';
            app.solutionC3 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC3.Limits = [0 Inf];
            app.solutionC3.Visible = 'off';
            app.pipetteC3 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC3.Limits = [0 Inf];
            app.pipetteC3.Visible = 'off';
            app.solutionC4 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC4.Limits = [0 Inf];
            app.solutionC4.Visible = 'off';
            app.pipetteC4 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC4.Limits = [0 Inf];
            app.pipetteC4.Visible = 'off';
            app.solutionC5 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC5.Limits = [0 Inf];
            app.solutionC5.Visible = 'off';
            app.pipetteC5 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC5.Limits = [0 Inf];
            app.pipetteC5.Visible = 'off';
            app.solutionC6 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC6.Limits = [0 Inf];
            app.solutionC6.Visible = 'off';
            app.pipetteC6 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC6.Limits = [0 Inf];
            app.pipetteC6.Visible = 'off';
            app.solutionC7 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC7.Limits = [0 Inf];
            app.solutionC7.Visible = 'off';
            app.pipetteC7 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC7.Limits = [0 Inf];
            app.pipetteC7.Visible = 'off';
            app.solutionC8 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC8.Limits = [0 Inf];
            app.solutionC8.Visible = 'off';
            app.pipetteC8 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC8.Limits = [0 Inf];
            app.pipetteC8.Visible = 'off';
            app.solutionC9 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC9.Limits = [0 Inf];
            app.solutionC9.Visible = 'off';
            app.pipetteC9 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC9.Limits = [0 Inf];
            app.pipetteC9.Visible = 'off';
            app.solutionC10 = uieditfield(app.UIFigure, 'numeric');
            app.solutionC10.Limits = [0 Inf];
            app.solutionC10.Visible = 'off';
            app.pipetteC10 = uieditfield(app.UIFigure, 'numeric');
            app.pipetteC10.Limits = [0 Inf];
            app.pipetteC10.Visible = 'off';
            app.valence1 = uieditfield(app.UIFigure, 'numeric');
            app.valence1.Visible = 'off';
            app.valence2 = uieditfield(app.UIFigure, 'numeric');
            app.valence2.Visible = 'off';
            app.valence3 = uieditfield(app.UIFigure, 'numeric');
            app.valence3.Visible = 'off';
            app.valence4 = uieditfield(app.UIFigure, 'numeric');
            app.valence4.Visible = 'off';
            app.valence5 = uieditfield(app.UIFigure, 'numeric');
            app.valence5.Visible = 'off';
            app.valence6 = uieditfield(app.UIFigure, 'numeric');
            app.valence6.Visible = 'off';
            app.valence7 = uieditfield(app.UIFigure, 'numeric');
            app.valence7.Visible = 'off';
            app.valence8 = uieditfield(app.UIFigure, 'numeric');
            app.valence8.Visible = 'off';
            app.valence9 = uieditfield(app.UIFigure, 'numeric');
            app.valence9.Visible = 'off';
            app.valence10 = uieditfield(app.UIFigure, 'numeric');
            app.valence10.Visible = 'off';
            app.mobility1 = uieditfield(app.UIFigure, 'numeric');
            app.mobility1.Limits = [0 Inf];
            app.mobility1.Visible = 'off';
            app.mobility2 = uieditfield(app.UIFigure, 'numeric');
            app.mobility2.Limits = [0 Inf];
            app.mobility2.Visible = 'off';
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = 'mV';
            app.Ion9Label = uilabel(app.UIFigure);
            app.Ion9Label.Text = 'Ion #9';
            app.IonDropDown9 = uidropdown(app.UIFigure);
            app.Ion10Label = uilabel(app.UIFigure);
            app.Ion10Label.Text = 'Ion #10';
            app.IonDropDown10 = uidropdown(app.UIFigure);
            app.mobility3 = uieditfield(app.UIFigure, 'numeric');
            app.mobility3.Limits = [0 Inf];
            app.mobility3.Visible = 'off';
            app.mobility4 = uieditfield(app.UIFigure, 'numeric');
            app.mobility4.Limits = [0 Inf];
            app.mobility4.Visible = 'off';
            app.mobility5 = uieditfield(app.UIFigure, 'numeric');
            app.mobility5.Limits = [0 Inf];
            app.mobility5.Visible = 'off';
            app.mobility6 = uieditfield(app.UIFigure, 'numeric');
            app.mobility6.Limits = [0 Inf];
            app.mobility6.Visible = 'off';
            app.mobility7 = uieditfield(app.UIFigure, 'numeric');
            app.mobility7.Limits = [0 Inf];
            app.mobility7.Visible = 'off';
            app.mobility8 = uieditfield(app.UIFigure, 'numeric');
            app.mobility8.Limits = [0 Inf];
            app.mobility8.Visible = 'off';
            app.mobility9 = uieditfield(app.UIFigure, 'numeric');
            app.mobility9.Limits = [0 Inf];
            app.mobility9.Visible = 'off';
            app.mobility10 = uieditfield(app.UIFigure, 'numeric');
            app.mobility10.Limits = [0 Inf];
            app.mobility10.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = JPcalculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end